classdef EqSol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EqSolUIFigure                 matlab.ui.Figure
        EqTextAxes                    matlab.ui.control.UIAxes
        SolutionTextAreaLabel         matlab.ui.control.Label
        SolutionTextArea              matlab.ui.control.TextArea
        ParTable                      matlab.ui.control.Table
        SubjectList                   matlab.ui.control.ListBox
        CalculateButton               matlab.ui.control.Button
        EqList                        matlab.ui.control.ListBox
        CustomEquationEditFieldLabel  matlab.ui.control.Label
        CustomEquationEditField       matlab.ui.control.EditField
        InputEquationButton           matlab.ui.control.Button
    end

    
    methods (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function DrawEq(app,Eq) %Eq - string. app - used to store textH
            Parameters=symvar(Eq); %find Parameters. cell array of strings
            for i=1:length(Parameters) %make Parameters symbolics
                eval(sprintf('%s = sym(''%s'');',Parameters{i},Parameters{i})); %a little retarded but works unlike syms
            end
            eval(['SEq=',Eq,';']); %create equation as a symbolic variable
            
            TextH=app.EqTextAxes.UserData; %Obtain text handle
            
            %add text in center
            TextH.String=['$' latex(SEq) '$'];
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function TableChange(app,Parameters)
            %introduces Parameters into user input table
            app.ParTable.Data=cell(length(Parameters),2);
            app.ParTable.Data(:,1)=Parameters;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function Eq = ReturnEq(app)
            %returns equations pointed by user selection (Eqlist) in string format
            
            if app.CustomEquationEditField.UserData %if custom equation is inputed
                Eq=app.CustomEquationEditField.Value;
                Eq=strrep(Eq,'=','=='); %replace syntax to fit.
            else
                
                switch app.EqList.Value
                    
                    case 'Pythgoras'
                        Eq='A^2+B^2==C^2';
                        
                    case 'Stifness Constant'
                        Eq='C==K_b/(K_b+K_m)';
                        
                    case 'Member Stifness (1)'
                        Eq='k_i==0.5774*pi*E*d/log(((1.155*t+D-d)*(D+d))/((1.155*t+D+d)*(D-d)))';
                        
                    case 'Member Stifness (2)'
                        Eq='k_m==E*d*A*exp(B*d/l)';
                        
                    case 'Bolt Stifness'
                        Eq='k_b==(A_d*A_t*E)/(A_d*l_t+A_t*l_d)';
                        
                    case 'Force in Bolt'
                        Eq='F_b==C*P+F_i';
                        
                    case 'Force in Member'
                        Eq='F_m==(1-C)*P-F_i';
                        
                    case 'Initial Force'
                        Eq='T_i==0.2*F_i*d';
                        
                    case 'Saftey Factor - Seperation'
                        Eq='n0==(F_i)/(P*(1-C))';
                        
                    case 'Saftey Factor - Proof Strength'
                        Eq='n_p==(S_p*A_t)/(C*P+F_i)';
                        
                    case 'Saftey Factor - Load'
                        Eq='n_L==(S_p*A_t-F_i)/(C*P)';
                        
                    case 'Raise Torque'
                        Eq='T_R==0.5*F*d_p*(pi*mu*d_p+l*cos(alpha))/(pi*d_p*cos(alpha)-mu*l)+0.5*F*mu_c*d_c';
                        
                    case 'Lower Torque'
                        Eq='TL==0.5*F*d_p*(pi*mu*d_p-l*cos(alpha))/(pi*d_p*cos(alpha)+mu*l)+0.5*F*mu_c*d_c';
                        
                    case 'Pitch Diameter for square threads'
                        Eq='d_p==d-0.5*p';
                        
                    case 'Lead'
                        Eq='l==n*p';
                        
                    case 'Interference Pressure(1)'
                        Eq='P==(delta)/(R*(((r0^2+R^2)/(r0^2-R^2)+v0)/E0+((R^2+r_i^2)/(R^2-r_i^2)-v_i)/E_i))';
                        
                    case 'Interference Pressure(2)'
                        Eq='P==(E*delta)/(2*R^3)*((r0^2-R^2)*(R^2-r_i^2))/(r0^2-r_i^2)';
                        
                    case 'Max fit Torque'
                        Eq='T_max==(pi*f*P*d^2*L)/2';
                        
                    case 'Max fit Force'
                        Eq='F_max==pi*f*P*d*L';
                        
                    case 'Hub Thermal Expansion'
                        Eq='d_T==(d_delta)/(R*alpha)';
                        
                    case 'Fit Torque by SetScrew'
                        Eq='T==0.5*d*F_i*(m_u1+m_u2)';
                        
                    case 'Minimal Radial Interference'
                        Eq='delta_r==0.5*(L_shaft-U_hub)';
                        
                    case 'Maximal Radial Interference'
                        Eq='delta_r==0.5*(U_shaft-L_hub)';
                        
                    case 'Tanget Shaft Stress'
                        Eq='sigma_t==-p*(d^2+d_i^2)/(d^2-d_i^2)';
                        
                    case 'Tanget Hub Stress'
                        Eq='sigma_t==p*(d0^2+d^2)/(d0^2-d^2)';
                        
                    case 'Radial Stress'
                        Eq='sigma_r==-p';
                        
                    case 'Equivalent Shaft and Hub StressJPG'
                        Eq='sigma_eq==sqrt(sigma_r^2+sigma_t^2-sigma_r*sigma_t)';
                        
                    case 'Belt Static Tension'
                        Eq='T==delta*k';
                        
                    case 'Required Belt Deflection'
                        Eq='Q==P/150';
                        
                    case 'Belt Required Elongation'
                        Eq='delta==2*(0.25*P^2+Q^2)^0.5-P';
                        
                    case 'Disk Brake Uniform Wear - Force'
                        Eq='F==0.25*P*Theta*d*(D-d)';
                        
                    case 'Disk Brake Uniform Wear - Torque'
                        Eq='T==0.0625*f*P*Theta*d*(D^2-d^2)';
                        
                end
            end
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %initalize lists for first equation
            app.SubjectList.Value='General';
            app.EqList.Items={'Pythgoras'}';
            app.EqList.Value={'Pythgoras'};
            
            %Initalize table
            TableChange(app,symvar(ReturnEq(app))); %writes equation down on axis
            
            %initalize axes
            % Remove title, axis labels, tick labels, and box
            title(app.EqTextAxes,'Equation:','FontSize',20);
            xlabel(app.EqTextAxes,[]);
            ylabel(app.EqTextAxes,[]);
            app.EqTextAxes.XAxis.TickLabels = {};
            app.EqTextAxes.YAxis.TickLabels = {};
            app.EqTextAxes.Box='on';
            
            %initalize text handle on axes
            syms A B C
            SEq= A^2+B^2 == C^2; %symbolic equation
            TextH=text(app.EqTextAxes,0.05,0.6,['$' latex(SEq) '$'],'Interpreter','latex','FontSize',15);
            app.EqTextAxes.UserData=TextH;
            
            app.CustomEquationEditField.UserData=0; %flag down for custom equation
        end

        % Value changed function: EqList
        function EqListValueChanged(app, event)
            %kill existing texts
            app.SolutionTextArea.Value='';
            
            %kill custom equation and flag it down
            app.CustomEquationEditField.UserData=0; %flag down for custom equation
            app.CustomEquationEditField.Value='';
            
            %update shown equation and table
            Eq=ReturnEq(app);
            DrawEq(app,Eq);
            TableChange(app,symvar(Eq));
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Eq=ReturnEq(app); %Obtain equation (string)
            Uinput=app.ParTable.Data(:,2); %Obtain numeric user input
            VarInd=find(cellfun('isempty',Uinput)); %identify the variables (to which we compute for) indicies in Uinput
            
            switch true
                case isempty(VarInd)
                    app.SolutionTextArea.Value='App computes for Parameters left undetermind.';
                    
                case length(VarInd)==1
                    OneVar(app,VarInd,Uinput,Eq);
                    
                case length(VarInd)==2
                    TwoVar(VarInd,Uinput,Eq);
                    
                otherwise
                    app.SolutionTextArea.Value='too many Parameters left undetermind';
                    return
                    
            end
        end

        % Value changed function: SubjectList
        function SubjectListValueChanged(app, event)
            switch app.SubjectList.Value
                case 'General'
                    app.EqList.Items={'Pythgoras'};
                    
                case 'Bolt Fasteners'
                    app.EqList.Items={'Bolt Stifness','Member Stifness (1)', 'Member Stifness (2)', 'Stifness Constant',...
                        'Force in Bolt','Force in Member','Initial Force','Saftey Factor - Seperation','Saftey Factor - Proof Strength',...
                        'Saftey Factor - Load'};
                case 'Power Screws'
                    app.EqList.Items={'Raise Torque','Lower Torque','Pitch Diameter for square threads','Lead'};
                    
                case 'Shaft-Hub Interference'
                    app.EqList.Items={'Interference Pressure(1)','Interference Pressure(2)','Max fit Torque','Max fit Force',...
                        'Hub Thermal Expansion','Fit Torque by SetScrew','Minimal Radial Interference','Maximal Radial Interference',...
                        'Tanget Shaft Stress','Tanget Hub Stress','Radial Stress','Equivalent Shaft and Hub StressJPG'};
                    
                case 'Timing Belt'
                    app.EqList.Items={'Belt Static Tension','Required Belt Deflection','Belt Required Elongation'};
                    
                case 'Brakes'
                    app.EqList.Items={'Disk Brake Uniform Wear - Force','Disk Brake Uniform Wear - Torque'};
                    
            end
            EqListValueChanged(app,event);
        end

        % Button pushed function: InputEquationButton
        function InputEquationButtonPushed(app, event)
            CstmEq=app.CustomEquationEditField.Value;
            if (length(strfind(CstmEq,'='))~=1) %check if its a legit equation
                errordlg('Please input an equation','EqSol Manager');
                app.CustomEquationEditField.Value='';
                return
            end
            
            app.CustomEquationEditField.UserData=1; %flag up for custom equation
            
            %Obtain equation
            CstmEq=ReturnEq(app);
            
            %kill existing texts
            app.SolutionTextArea.Value='';
            
            %update shown equation and table
            DrawEq(app,CstmEq);
            TableChange(app,symvar(CstmEq));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EqSolUIFigure = uifigure;
            app.EqSolUIFigure.Name = 'EqSol';
            app.EqTextAxes = uiaxes(app.EqSolUIFigure);
            title(app.EqTextAxes, 'Reference')
            app.SolutionTextAreaLabel = uilabel(app.EqSolUIFigure);
            app.SolutionTextAreaLabel.Text = 'Solution';
            app.SolutionTextArea = uitextarea(app.EqSolUIFigure);
            app.ParTable = uitable(app.EqSolUIFigure);
            app.ParTable.ColumnName = {'Parameter'; 'Value'};
            app.ParTable.RowName = {};
            app.ParTable.ColumnEditable = [false true];
            app.SubjectList = uilistbox(app.EqSolUIFigure);
            app.SubjectList.Items = {'General', 'Bolt Fasteners', 'Power Screws', 'Shaft-Hub Interference', 'Timing Belt', 'Brakes'};
            app.SubjectList.ValueChangedFcn = createCallbackFcn(app, @SubjectListValueChanged, true);
            app.SubjectList.Value = 'General';
            app.CalculateButton = uibutton(app.EqSolUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EqList = uilistbox(app.EqSolUIFigure);
            app.EqList.Items = {'placeholder'};
            app.EqList.ValueChangedFcn = createCallbackFcn(app, @EqListValueChanged, true);
            app.EqList.Value = {};
            app.CustomEquationEditFieldLabel = uilabel(app.EqSolUIFigure);
            app.CustomEquationEditFieldLabel.Text = 'Custom Equation';
            app.CustomEquationEditField = uieditfield(app.EqSolUIFigure, 'text');
            app.InputEquationButton = uibutton(app.EqSolUIFigure, 'push');
            app.InputEquationButton.ButtonPushedFcn = createCallbackFcn(app, @InputEquationButtonPushed, true);
            app.InputEquationButton.Text = 'Input Equation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EqSol

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EqSolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EqSolUIFigure)
        end
    end
end